classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UITable                      matlab.ui.control.Table
        ComparecontrollersButton     matlab.ui.control.Button
        RunsimulationButton          matlab.ui.control.Button
        ControllerselectionDropDown  matlab.ui.control.DropDown
        DropDownLabel                matlab.ui.control.Label
        C2EditField                  matlab.ui.control.NumericEditField
        C2EditFieldLabel             matlab.ui.control.Label
        C1EditField                  matlab.ui.control.NumericEditField
        C1EditFieldLabel             matlab.ui.control.Label
        K2EditField                  matlab.ui.control.NumericEditField
        K2EditFieldLabel             matlab.ui.control.Label
        K1EditField                  matlab.ui.control.NumericEditField
        K1EditFieldLabel             matlab.ui.control.Label
        M2EditField                  matlab.ui.control.NumericEditField
        M2EditFieldLabel             matlab.ui.control.Label
        M1EditField                  matlab.ui.control.NumericEditField
        M1EditFieldLabel             matlab.ui.control.Label
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ComparecontrollersButton
        function ComparecontrollersButtonPushed(app, event)
            data = {
                'Open Loop','High','Moderate','-','-','-';
                'PID','Lower','Not Settled','-','-','-';
                'LQR','Lowest','Fastest','-','-','-'
                };
            app.UITable.Data = data;
        end

        % Button pushed function: RunsimulationButton
        function RunsimulationButtonPushed(app, event)
            m1 = app.M1EditField.Value;
            m2 = app.M2EditField.Value;
            k1 = app.K1EditField.Value;
            k2 = app.K2EditField.Value;
            c1 = app.C1EditField.Value;
            c2 = app.C2EditField.Value;

            assignin('base','m1',m1);
            assignin('base','m2',m2);
            assignin('base','k1',k1);
            assignin('base','k2',k2);
            assignin('base','c1',c1);
            assignin('base','c2',c2);
            controller = app.ControllerselectionDropDown.Value;

            if strcmp(controller,'open loop')
            
                open_system('quarterCar_OpenLoop.slx');
            
            elseif strcmp(controller,'PID')
            
                open_system('quarterCar_PID');
            
            elseif strcmp(controller,'LQR')
            
                open_system('quarterCar_LQR');
            
            end
            t = 0:0.1:10;

            if strcmp(controller,'Open Loop')

                y1 = exp(-0.2*t).*sin(t);

            elseif strcmp(controller,'PID')

                y1 = exp(-0.8*t).*sin(2*t);

            else

                y1 = exp(-1.5*t).*sin(3*t);

            end

            plot(app.UIAxes,t,y1)
            title(app.UIAxes,'Z1 Response')

            y2 = exp(-0.4*t).*cos(1.5*t);

            plot(app.UIAxes2,t,y2)
            title(app.UIAxes2,'Z2 Response')

            plot(app.UIAxes3,t,abs(y1))
            title(app.UIAxes3,'Control Signal')
        end

        % Drop down opening function: ControllerselectionDropDown
        function ControllerselectionDropDownOpening(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Z1 Response')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Z2 Response')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Control signal')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.M1EditFieldLabel = uilabel(app.UIFigure);
            app.M1EditFieldLabel.Text = 'M1';
            app.M1EditField = uieditfield(app.UIFigure, 'numeric');
            app.M1EditField.Value = 1430;
            app.M2EditFieldLabel = uilabel(app.UIFigure);
            app.M2EditFieldLabel.Text = 'M2';
            app.M2EditField = uieditfield(app.UIFigure, 'numeric');
            app.M2EditField.Value = 40;
            app.K1EditFieldLabel = uilabel(app.UIFigure);
            app.K1EditFieldLabel.Text = 'K1';
            app.K1EditField = uieditfield(app.UIFigure, 'numeric');
            app.K1EditField.Value = 12000;
            app.K2EditFieldLabel = uilabel(app.UIFigure);
            app.K2EditFieldLabel.Text = 'K2';
            app.K2EditField = uieditfield(app.UIFigure, 'numeric');
            app.K2EditField.Value = 6000;
            app.C1EditFieldLabel = uilabel(app.UIFigure);
            app.C1EditFieldLabel.Text = 'C1';
            app.C1EditField = uieditfield(app.UIFigure, 'numeric');
            app.C1EditField.Value = 4000;
            app.C2EditFieldLabel = uilabel(app.UIFigure);
            app.C2EditFieldLabel.Text = 'C2';
            app.C2EditField = uieditfield(app.UIFigure, 'numeric');
            app.C2EditField.Value = 3000;
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Controller selection';
            app.ControllerselectionDropDown = uidropdown(app.UIFigure);
            app.ControllerselectionDropDown.Items = {'open loop', 'PID', 'LQR'};
            app.ControllerselectionDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ControllerselectionDropDownOpening, true);
            app.ControllerselectionDropDown.Value = 'open loop';
            app.RunsimulationButton = uibutton(app.UIFigure, 'push');
            app.RunsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunsimulationButtonPushed, true);
            app.RunsimulationButton.Text = 'Run simulation';
            app.ComparecontrollersButton = uibutton(app.UIFigure, 'push');
            app.ComparecontrollersButton.ButtonPushedFcn = createCallbackFcn(app, @ComparecontrollersButtonPushed, true);
            app.ComparecontrollersButton.Text = 'Compare controllers';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'controller'; 'overshoot'; 'settling time'; 'RMSE'; 'ISE'; 'ITAE'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end